classdef AppPepeMich2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GraficarxlsxButton  matlab.ui.control.Button
        CALCULADORADEREGRESINLINEALLabel  matlab.ui.control.Label
        AgregarButton       matlab.ui.control.Button
        ReadxlsxButton      matlab.ui.control.Button
        OButton             matlab.ui.control.Button
        Label               matlab.ui.control.Label
        R2EditField         matlab.ui.control.EditField
        R2EditFieldLabel    matlab.ui.control.Label
        bEditField          matlab.ui.control.EditField
        bEditFieldLabel     matlab.ui.control.Label
        mEditField          matlab.ui.control.EditField
        mEditFieldLabel     matlab.ui.control.Label
        yEditField          matlab.ui.control.EditField
        yEditFieldLabel     matlab.ui.control.Label
        ymxbLabel           matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        GraficarButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SelectedFilePath string % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        datos = [0, 0];
        app.UITable.Data = datos;
        end

        % Callback function
        function Enviar(app, event)

        end

        % Callback function
        function E(app, event)

        end

        % Button pushed function: ReadxlsxButton
        function ReadxlsxButtonPushed(app, event)

            if ~isempty(app.SelectedFilePath)
                % Leer la tabla desde el archivo Excel
                t = readtable(app.SelectedFilePath, 'Sheet', 1);

                % Configurar la tabla en la interfaz gráfica
                app.UITable.ColumnName = t.Properties.VariableNames;
                app.UITable.Data = t;
                app.UITable.ColumnEditable = true;
                app.UITable.CellEditCallback = @app.editCell;

                % Graficar la tabla en el eje
                %scatter(app.UIAxes, app.UITable.Data.x, app.UITable.Data.y);
            else
                disp('Por favor, seleccione un archivo antes de presionar el botón "ReadxlsxButton".');
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %cla(app.UIAxes);
            %scatter(app.UIAxes, app.UITable.Data.x, app.UITable.Data.y);
            datos = app.UITable.Data;
            [x_order, order_x] = sort(datos(:, 1));
            y_order = datos(order_x, 2);
            %x = app.UITable.Data{:, 1};
            %y = app.UITable.Data{:, 2};

            %[x_order, order_x] = sort(x);
            %y_order= y(order_x);

            coeficientes = polyfit(x_order, y_order, 1);
            m = coeficientes(1);
            b = coeficientes(2);

            hold(app.UIAxes,'on');
            %scatter(app.UIAxes, x, y, 'o', 'filled', 'MarkerFaceColor', 'b');
            scatter(app.UIAxes, datos(:, 1), datos(:, 2), 'o', 'filled', 'MarkerFaceColor', 'b');

            y_po = m * x_order + b;%pendiente odenada

            plot(app.UIAxes, x_order, y_po, 'r--', 'LineWidth', 1);

            xlabel(app.UIAxes, 'Eje X');
            ylabel(app.UIAxes, 'Eje Y');
            title(app.UIAxes, 'Regresión Lineal');

            legend(app.UIAxes, {'Datos', 'Regresión Lineal'});

            equation_str = sprintf('y = %.2fx + %.2f', m, b);

            x_center = mean(x_order);
            y_center = mean(y_po);

            text(app.UIAxes, x_center, y_center, equation_str, 'Color', 'r', 'HorizontalAlignment', 'center');

            ec = sprintf('y = %.4f * x + %.4f', m, b);
            app.yEditField.Value = ec;


            app.mEditField.Value = sprintf('%.4f', m);
            app.bEditField.Value = sprintf('%.4f', b);

            %correlation_coefficient = corrcoef(x, y);
            correlation_coefficient = corrcoef(datos(:, 1), datos(:, 2));
            r = correlation_coefficient(1, 2);

            corr= sprintf('%.4f', r);
            app.R2EditField.Value = corr;


        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)


        end

        % Callback function: not associated with a component
        function RegresionLinealButtonPushed(app, event)
            % % Ajustar un modelo de regresión lineal
            % model = fitlm(app.UITable.Data.x, app.UITable.Data.y);
            % 
            % % Obtener los coeficientes del modelo
            % coeficientes = model.Coefficients.Estimate;
            % 
            % % Calcular los valores y correspondientes a los valores x para la línea de regresión
            % x_valores = linspace(min(app.UITable.Data.x), max(app.UITable.Data.x), 100);
            % y_valores = coeficientes(1) + coeficientes(2) * x_valores;
            % 
            % % Graficar la línea de regresión
            % scatter(app.UIAxes, app.UITable.Data.x, app.UITable.Data.y);
            % hold(app.UIAxes, 'on');  % Mantener la gráfica existente
            % plot(app.UIAxes, x_valores, y_valores, 'r', 'LineWidth', 2);

        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            % Diálogo para seleccionar un archivo
            [file, path] = uigetfile('*.*', 'Seleccionar un archivo');

            % Comprobar si el usuario ha cancelado la selección
            if isequal(file, 0)
                disp('Selección de archivo cancelada por el usuario.');
            else
                % Construir la ruta completa del archivo seleccionado
                selectedFilePath = fullfile(path, file);

                % Mostrar la ruta del archivo seleccionado en la etiqueta
                app.Label.Text = [file];

                app.SelectedFilePath = fullfile(path, file);

                % Realizar otras operaciones con el archivo seleccionado
                disp(['Archivo seleccionado: ', selectedFilePath]);
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
                         % Obtén los datos actuales de la tabla
    %datos = app.UITable.Data;

    % Agrega una nueva fila con ceros
    %nuevaFila = zeros(1, size(datos, 2));
    %datos = [datos; nuevaFila];

    % Actualiza los datos en la tabla
    %app.UITable.Data = datos;
        % Obtén los datos actuales de la tabla
    datos = app.UITable.Data;

    % Obtén el número actual de columnas
    num_columnas = size(datos, 2);

    % Agrega una nueva fila con ceros
    nuevaFila = zeros(1, num_columnas);
    datos = [datos; nuevaFila];

    % Actualiza los datos en la tabla
    app.UITable.Data = datos;
        end

        % Button pushed function: GraficarxlsxButton
        function GraficarxlsxButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ymxbLabel = uilabel(app.UIFigure);
            app.ymxbLabel.Text = 'y = mx+b';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y=';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm=';
            app.mEditField = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b=';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R^2=';
            app.R2EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OButton = uibutton(app.UIFigure, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.ReadxlsxButton = uibutton(app.UIFigure, 'push');
            app.ReadxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ReadxlsxButtonPushed, true);
            app.ReadxlsxButton.Text = 'Read.xlsx';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.CALCULADORADEREGRESINLINEALLabel = uilabel(app.UIFigure);
            app.CALCULADORADEREGRESINLINEALLabel.Text = 'CALCULADORA DE REGRESIÓN LINEAL';
            app.GraficarxlsxButton = uibutton(app.UIFigure, 'push');
            app.GraficarxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarxlsxButtonPushed, true);
            app.GraficarxlsxButton.Text = 'Graficar xlsx';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppPepeMich2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end